classdef app_backup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image2                       matlab.ui.control.Image
        ResetButton                  matlab.ui.control.Button
        SimSpeedInput                matlab.ui.control.NumericEditField
        SimulationspeedLabel         matlab.ui.control.Label
        ChargersListBox              matlab.ui.control.ListBox
        ChargersListBoxLabel         matlab.ui.control.Label
        CarqueueListBox              matlab.ui.control.ListBox
        CarqueueListBoxLabel         matlab.ui.control.Label
        TimeTextArea                 matlab.ui.control.TextArea
        TimeTextAreaLabel            matlab.ui.control.Label
        arrivalrate                  matlab.ui.control.NumericEditField
        CararrivalrateLabel          matlab.ui.control.Label
        EVbatterycapacity            matlab.ui.control.NumericEditField
        EVbatterycapacityEditFieldLabel  matlab.ui.control.Label
        powerrating                  matlab.ui.control.NumericEditField
        ChargerpowerratingLabel      matlab.ui.control.Label
        numberofports                matlab.ui.control.NumericEditField
        NumberofchargingportsLabel   matlab.ui.control.Label
        RealTimeDataAnalysisPanel    matlab.ui.container.Panel
        TotalWaitingLabel            matlab.ui.control.Label
        TotalCostLabel               matlab.ui.control.Label
        TotalEnergyLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        viewreportexportplotsButton  matlab.ui.control.Button
        startstopsimulateButton      matlab.ui.control.Button
        EVchargingstationsLabel      matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        NumberOfPorts = 10 % Number of charging ports
        PowerRating = 50 % [kW] Power rating of a charging port
        BatterCapacity = 40 % [kWh] Total capacity of a car
        ArrivalRate = 0.3 % Average number of cars arriving per minute
        SimSpeed = 10;

        mTimer    % timer
        mCounter = 0; % integer counter
        running = 0 % Is the timer running

        station = ChargeStation(10, 50)

        TotalEnergyPerTime
        TotalCostPerTime
        TotalCarsWaitingPerTime
    end
    
    methods (Access = private)
        
        function reset_sim(app)
            if app.running == 1
                stop(app.mTimer);
            end
            app.mCounter = 0;
            app.running = 0;
            app.TotalEnergyPerTime = [];
            app.TotalCostPerTime = [];
            app.TotalCarsWaitingPerTime = [];
            app.TimeTextArea.Value = "00:00";
            app.station = ChargeStation(app.NumberOfPorts, app.PowerRating);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: numberofports
        function numberofportsValueChanged(app, event)
            app.NumberOfPorts = app.numberofports.Value;
            app.reset_sim();
            
            %changing ports
            app.Image2.ImageSource = imread(pwd + "/Images/" + string(app.numberofports.Value) + " ports.png");

            %changing cars
            %app.Image2.ImageSource = imread(pwd + "\Images\" + string(value) + " ports " + string(value2) + " cars.png");

        end

        % Button pushed function: startstopsimulateButton
        function startstopsimulateButtonPushed(app, event)

            if app.running == 0
                app.running = 1;
                app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', 0.1);
                start(app.mTimer);
            else
                app.running = 0;
                stop(app.mTimer);
            end
            
            % this is the callback for the timer
            function timerCallback(~, ~)                
                if app.mCounter == 0
                    app.station = ChargeStation(app.NumberOfPorts, app.PowerRating);
                end
                for minute=1:app.SimSpeed
                    app.mCounter = app.mCounter + 1;
                    [hours, minutes] = minutes_to_time(app.mCounter);
                    app.TimeTextArea.Value = num2str(hours) + ":" + num2str(minutes);
                    new_cars = arrive_cars(app.mCounter, app.ArrivalRate, app.BatterCapacity);
                    app.station = app.station.addCars(new_cars);
                    app.station = app.station.update(app.mCounter);

                    total_power = app.station.getTotalPower();
                    total_cost = app.station.getCost();
                    total_waiting = app.station.carsWaiting();

                    app.TotalEnergyPerTime(app.mCounter) = total_power;
                    app.TotalCostPerTime(app.mCounter) = total_cost;
                    app.TotalCarsWaitingPerTime(app.mCounter) = total_waiting;

                    app.TotalWaitingLabel.Text = num2str(total_waiting);
                    app.TotalCostLabel.Text = num2str(total_cost);
                    app.TotalEnergyLabel.Text = num2str(total_power);
                        
                end
                app.CarqueueListBox.Items = "";
                for queued_car=1:numel(app.station.CarQueue)
                    car_string = num2str(app.station.CarQueue(queued_car).getPercentage()*100) + "%";
                    app.CarqueueListBox.Items = [ app.CarqueueListBox.Items, car_string];
                end
                
                app.ChargersListBox.Items = "";
                for charging_car=1:numel(app.station.Chargers)
                    if app.station.Chargers(charging_car).isFree()
                        car_string = "charger " + num2str(charging_car) + ": Free";
                    else
                        car_string = "charger " + num2str(charging_car) + ": " + num2str(app.station.Chargers(charging_car).ChargingCar.getPercentage()*100)+"%";
                    end
                    
                    app.ChargersListBox.Items = [ app.ChargersListBox.Items, car_string];
                end

                plot(app.UIAxes_3, app.TotalEnergyPerTime);
                

                app.Image2.ImageSource = imread(pwd + "\Images\" + app.NumberOfPorts + " ports " + string(app.station.getOccupied()+1) + " cars.png");


                plot(app.UIAxes, app.TotalCostPerTime);
                
                plot(app.UIAxes_2, app.TotalCarsWaitingPerTime);
                if app.mCounter >= 60*24
                   app.reset_sim();
                end

            end
        end

        % Value changed function: powerrating
        function powerratingValueChanged(app, event)
            app.PowerRating = app.powerrating.Value;
            app.reset_sim();
        end

        % Value changed function: EVbatterycapacity
        function EVbatterycapacityValueChanged(app, event)
            app.BatterCapacity = app.EVbatterycapacity.Value;
            
        end

        % Value changed function: arrivalrate
        function arrivalrateValueChanged(app, event)
            app.ArrivalRate = app.arrivalrate.Value;
            
        end

        % Value changed function: SimSpeedInput
        function SimSpeedInputValueChanged(app, event)
            app.SimSpeed = app.SimSpeedInput.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset_sim();
        end

        % Button pushed function: viewreportexportplotsButton
        function viewreportexportplotsButtonPushed(app, event)
            
            % Create a tiled layout to arrange the plots in a grid
            t = tiledlayout(3, 1);

            % Capture and arrange the plots in the tiles
            ax1 = nexttile;
            copyobj(app.UIAxes_3.Children, ax1);
            title(ax1, 'Total power');

            ax2 = nexttile;
            copyobj(app.UIAxes.Children, ax2);
            title(ax2, 'Cost');

            ax3 = nexttile;
            copyobj(app.UIAxes_2.Children, ax3);
            title(ax3, 'Cars waiting');

            % Save the combined plots as an image
            exportFileName = 'simulation_output_plots.pdf';
            exportgraphics(t, exportFileName);

            msgbox(['Plots exported to: ' exportFileName]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EV-charging-stations.jpg');
            app.EVchargingstationsLabel = uilabel(app.UIFigure);
            app.EVchargingstationsLabel.Text = 'EV charging stations';
            app.startstopsimulateButton = uibutton(app.UIFigure, 'push');
            app.startstopsimulateButton.ButtonPushedFcn = createCallbackFcn(app, @startstopsimulateButtonPushed, true);
            app.startstopsimulateButton.Text = 'start/stop simulate';
            app.viewreportexportplotsButton = uibutton(app.UIFigure, 'push');
            app.viewreportexportplotsButton.ButtonPushedFcn = createCallbackFcn(app, @viewreportexportplotsButtonPushed, true);
            app.viewreportexportplotsButton.Text = {'view report'; '(export plots)'};
            app.RealTimeDataAnalysisPanel = uipanel(app.UIFigure);
            app.RealTimeDataAnalysisPanel.Title = 'Real Time Data Analysis';
            app.UIAxes_3 = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes_3, 'Total Energy transfered')
            xlabel(app.UIAxes_3, 'Time of day')
            ylabel(app.UIAxes_3, 'Total energy transferred')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [0 240 480 720 960 1200 1440];
            app.UIAxes_3.XTickLabel = {'00:00'; '04:00'; '08:00'; '12:00'; '16:00'; '20:00'; '24:00'};
            app.UIAxes_2 = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes_2, 'EVs waiting')
            xlabel(app.UIAxes_2, 'Time of day')
            ylabel(app.UIAxes_2, 'Number of cars waiting')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [0 240 480 720 960 1200 1440];
            app.UIAxes_2.XTickLabel = {'00:00'; '04:00'; '08:00'; '12:00'; '16:00'; '20:00'; '24:00'};
            app.UIAxes = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes, 'cost')
            xlabel(app.UIAxes, 'Time of day')
            ylabel(app.UIAxes, 'Cost in Euro')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 240 480 720 960 1200 1440];
            app.UIAxes.XTickLabel = {'00:00'; '04:00'; '08:00'; '12:00'; '16:00'; '20:00'; '24:00'};
            app.TotalEnergyLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.TotalEnergyLabel.Text = '0';
            app.TotalCostLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.TotalCostLabel.Text = '0';
            app.TotalWaitingLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.TotalWaitingLabel.Text = '0';
            app.NumberofchargingportsLabel = uilabel(app.UIFigure);
            app.NumberofchargingportsLabel.Text = 'Number of charging ports';
            app.numberofports = uieditfield(app.UIFigure, 'numeric');
            app.numberofports.ValueChangedFcn = createCallbackFcn(app, @numberofportsValueChanged, true);
            app.numberofports.Value = 10;
            app.ChargerpowerratingLabel = uilabel(app.UIFigure);
            app.ChargerpowerratingLabel.Text = 'Charger power rating';
            app.powerrating = uieditfield(app.UIFigure, 'numeric');
            app.powerrating.ValueChangedFcn = createCallbackFcn(app, @powerratingValueChanged, true);
            app.powerrating.Value = 50;
            app.EVbatterycapacityEditFieldLabel = uilabel(app.UIFigure);
            app.EVbatterycapacityEditFieldLabel.Text = 'EV battery capacity';
            app.EVbatterycapacity = uieditfield(app.UIFigure, 'numeric');
            app.EVbatterycapacity.ValueChangedFcn = createCallbackFcn(app, @EVbatterycapacityValueChanged, true);
            app.EVbatterycapacity.Value = 40;
            app.CararrivalrateLabel = uilabel(app.UIFigure);
            app.CararrivalrateLabel.Text = 'Car arrival rate';
            app.arrivalrate = uieditfield(app.UIFigure, 'numeric');
            app.arrivalrate.ValueChangedFcn = createCallbackFcn(app, @arrivalrateValueChanged, true);
            app.arrivalrate.Value = 0.3;
            app.TimeTextAreaLabel = uilabel(app.UIFigure);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.UIFigure);
            app.TimeTextArea.Value = {'20'};
            app.CarqueueListBoxLabel = uilabel(app.UIFigure);
            app.CarqueueListBoxLabel.Text = 'Car queue';
            app.CarqueueListBox = uilistbox(app.UIFigure);
            app.CarqueueListBox.Items = {};
            app.CarqueueListBox.Value = {};
            app.ChargersListBoxLabel = uilabel(app.UIFigure);
            app.ChargersListBoxLabel.Text = 'Chargers';
            app.ChargersListBox = uilistbox(app.UIFigure);
            app.ChargersListBox.Items = {};
            app.ChargersListBox.Value = {};
            app.SimulationspeedLabel = uilabel(app.UIFigure);
            app.SimulationspeedLabel.Text = 'Simulation speed';
            app.SimSpeedInput = uieditfield(app.UIFigure, 'numeric');
            app.SimSpeedInput.ValueChangedFcn = createCallbackFcn(app, @SimSpeedInputValueChanged, true);
            app.SimSpeedInput.Value = 10;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_backup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end