classdef app_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        StimulationTab                matlab.ui.container.Tab
        RealTimeDataAnalysisPanel     matlab.ui.container.Panel
        ChargersListBox               matlab.ui.control.ListBox
        ChargersListBoxLabel          matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        CarqueueListBox               matlab.ui.control.ListBox
        CarqueueListBoxLabel          matlab.ui.control.Label
        TotalWaitingLabel             matlab.ui.control.Label
        TotalCostLabel                matlab.ui.control.Label
        TotalEnergyLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        viewreportexportplotsButton   matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        startstopsimulateButton       matlab.ui.control.Button
        TimeTextArea                  matlab.ui.control.TextArea
        TimeTextAreaLabel             matlab.ui.control.Label
        SimSpeedInput                 matlab.ui.control.NumericEditField
        SimulationspeedLabel          matlab.ui.control.Label
        arrivalrate                   matlab.ui.control.NumericEditField
        CararrivalrateLabel           matlab.ui.control.Label
        EVbatterycapacity             matlab.ui.control.NumericEditField
        EVbatterycapacityEditFieldLabel  matlab.ui.control.Label
        powerrating                   matlab.ui.control.NumericEditField
        ChargerpowerratingLabel       matlab.ui.control.Label
        numberofports                 matlab.ui.control.NumericEditField
        NumberofchargingportsLabel    matlab.ui.control.Label
        EVchargingstationsLabel       matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        OptimizationTab               matlab.ui.container.Tab
        ResultsPanel                  matlab.ui.container.Panel
        ValueObjectiveFunctionLabel   matlab.ui.control.Label
        OptimalNumberofportsLabel     matlab.ui.control.Label
        OptiAxes                      matlab.ui.control.UIAxes
        OptimizationRunningLamp       matlab.ui.control.Lamp
        OptimizationRunningLampLabel  matlab.ui.control.Label
        ObjectiveDropDown             matlab.ui.control.DropDown
        ObjectiveDropDownLabel        matlab.ui.control.Label
        OptimizeButton                matlab.ui.control.Button
        Image4                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        NumberOfPorts = 10 % Number of charging ports
        PowerRating = 50 % [kW] Power rating of a charging port
        BatterCapacity = 40 % [kWh] Total capacity of a car
        ArrivalRate = 0.3 % Average number of cars arriving per minute
        SimSpeed = 10;

        mTimer    % timer
        mCounter = 0; % integer counter
        running = 0 % Is the timer running

        station = ChargeStation(10, 50)

        TotalEnergyPerTime
        TotalCostPerTime
        TotalCarsWaitingPerTime
        n_ports_optimal % Description
        revenue_optimal
        waiting_time_optimal
    end
    
    methods (Access = private)
        
        function reset_sim(app)
            if app.running == 1
                stop(app.mTimer);
            end
            app.mCounter = 0;
            app.running = 0;
            app.TotalEnergyPerTime = [];
            app.TotalCostPerTime = [];
            app.TotalCarsWaitingPerTime = [];
            app.TimeTextArea.Value = "00:00";
            app.station = ChargeStation(app.NumberOfPorts, app.PowerRating);
        end
        
        function revenue = revenue_function(app,ChargeStation, number_ports, VC_m_per_port)
            %VCM_per_port is per hour
                revenue = sum([ChargeStation.Chargers.TotalCost])*1.3 - sum([ChargeStation.Chargers.TotalCost]) - number_ports * VC_m_per_port *24;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: numberofports
        function numberofportsValueChanged(app, event)
            app.NumberOfPorts = app.numberofports.Value;
            app.reset_sim();
            
            %changing port images
            %app.Image2.ImageSource = imread(pwd + string(filesep) + "Images" + string(filesep) + string(app.numberofports.Value) + " ports 0 cars.png");
            app.Image2.ImageSource = imread("Images" + string(filesep) + string(app.numberofports.Value) + " ports 0 cars.png");


        end

        % Button pushed function: startstopsimulateButton
        function startstopsimulateButtonPushed(app, event)

            if app.running == 0
                app.running = 1;
                app.mTimer = timer('TimerFcn', @timerCallback, 'ExecutionMode', 'FixedRate', 'Period', 0.1);
                start(app.mTimer);
            else
                app.running = 0;
                stop(app.mTimer);
            end
            
            % this is the callback for the timer
            function timerCallback(~, ~)                
                if app.mCounter == 0
                    app.station = ChargeStation(app.NumberOfPorts, app.PowerRating);
                end
                for minute=1:app.SimSpeed
                    app.mCounter = app.mCounter + 1;
                    [hours, minutes] = minutes_to_time(app.mCounter);
                    app.TimeTextArea.Value = num2str(hours) + ":" + num2str(minutes);
                    new_cars = arrive_cars(app.mCounter, app.ArrivalRate, app.BatterCapacity);
                    app.station = app.station.addCars(new_cars);
                    app.station = app.station.update(app.mCounter);

                    total_power = app.station.getTotalPower();
                    total_cost = app.station.getCost();
                    total_waiting = app.station.carsWaiting();

                    app.TotalEnergyPerTime(app.mCounter) = total_power;
                    app.TotalCostPerTime(app.mCounter) = total_cost;
                    app.TotalCarsWaitingPerTime(app.mCounter) = total_waiting;

                    app.TotalWaitingLabel.Text = num2str(total_waiting);
                    app.TotalCostLabel.Text = num2str(total_cost);
                    app.TotalEnergyLabel.Text = num2str(total_power);
                        
                end
                app.CarqueueListBox.Items = "";
                for queued_car=1:numel(app.station.CarQueue)
                    car_string = num2str(app.station.CarQueue(queued_car).getPercentage()*100) + "%";
                    app.CarqueueListBox.Items = [ app.CarqueueListBox.Items, car_string];
                end
                
                app.ChargersListBox.Items = "";
                for charging_car=1:numel(app.station.Chargers)
                    if app.station.Chargers(charging_car).isFree()
                        car_string = "charger " + num2str(charging_car) + ": Free";
                    else
                        car_string = "charger " + num2str(charging_car) + ": " + num2str(app.station.Chargers(charging_car).ChargingCar.getPercentage()*100)+"%";
                    end
                    
                    app.ChargersListBox.Items = [ app.ChargersListBox.Items, car_string];
                end

                plot(app.UIAxes_3, app.TotalEnergyPerTime);
                
                %changing car images
                %app.Image2.ImageSource = imread(pwd + string(filesep) + "Images" + string(filesep) + app.NumberOfPorts + " ports " + string(app.station.getOccupied()) + " cars.png");
                app.Image2.ImageSource = imread("Images" + string(filesep) + app.NumberOfPorts + " ports " + string(app.station.getOccupied()) + " cars.png");


                plot(app.UIAxes, app.TotalCostPerTime);
                
                plot(app.UIAxes_2, app.TotalCarsWaitingPerTime);
                if app.mCounter >= 60*24
                   app.reset_sim();
                end

            end
        end

        % Value changed function: powerrating
        function powerratingValueChanged(app, event)
            app.PowerRating = app.powerrating.Value;
            app.reset_sim();
        end

        % Value changed function: EVbatterycapacity
        function EVbatterycapacityValueChanged(app, event)
            app.BatterCapacity = app.EVbatterycapacity.Value;
            
        end

        % Value changed function: arrivalrate
        function arrivalrateValueChanged(app, event)
            app.ArrivalRate = app.arrivalrate.Value;
            
        end

        % Value changed function: SimSpeedInput
        function SimSpeedInputValueChanged(app, event)
            app.SimSpeed = app.SimSpeedInput.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset_sim();
        end

        % Button pushed function: viewreportexportplotsButton
        function viewreportexportplotsButtonPushed(app, event)
            
            % Create a tiled layout to arrange the plots in a grid
            t = tiledlayout(3, 1);

            % Capture and arrange the plots in the tiles
            ax1 = nexttile;
            copyobj(app.UIAxes_3.Children, ax1);
            title(ax1, 'Total power');

            ax2 = nexttile;
            copyobj(app.UIAxes.Children, ax2);
            title(ax2, 'Cost');

            ax3 = nexttile;
            copyobj(app.UIAxes_2.Children, ax3);
            title(ax3, 'Cars waiting');

            % Save the combined plots as an image
            exportFileName = 'simulation_output_plots.pdf';
            exportgraphics(t, exportFileName);

            msgbox(['Plots exported to: ' exportFileName]);
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonOushed(app, event)
            app.OptimizationRunningLamp.Visible = 'on';
            app.OptimizationRunningLamp.Enable = 'on';
            P_ports = app.powerrating.Value;
            C_bat = app.EVbatterycapacity.Value;
            AverageCarsPerMinute = app.arrivalrate.Value;
            Optimization_attribute = app.ObjectiveDropDown.Value;

            %fixed within the app
            VC_m_per_port = 5;
            minutes_in_day = 24*60;

            %list for creating graphs after optimization (with information from all 50 simulations)
            n_ports_tested = [];
            waiting_time_tested = [];
            revenue_tested = [];

            app.n_ports_optimal = 0;
            app.revenue_optimal = 0;
            app.waiting_time_optimal = 1000000000000000;

            if Optimization_attribute == "Revenue"
                for N_ports=1:20
                    station1 = ChargeStation(N_ports, P_ports);

                    % Start simulation
                    for minute=1:minutes_in_day
                        new_cars = arrive_cars(minute, AverageCarsPerMinute, C_bat);
                        station1 = station1.addCars(new_cars);
                        station1 = station1.update(minute);
                    end

                    revenue = app.revenue_function(station1, N_ports, VC_m_per_port);
                    waitingtime = station1.getWaitingTime()/station1.getCompletedCars(); % calculates average waiting time
                   
                    n_ports_tested = [n_ports_tested, N_ports];
                    waiting_time_tested = [waiting_time_tested, waitingtime];
                    revenue_tested = [revenue_tested, revenue];

                    if revenue > app.revenue_optimal
                        app.n_ports_optimal = N_ports;
                        app.revenue_optimal = revenue;
                        app.waiting_time_optimal = waitingtime;
                    end


                end

                app.OptiAxes.YLabel.String = "Revenue"
                scatter(app.OptiAxes, n_ports_tested, revenue_tested)
                app.OptimalNumberofportsLabel.Text = "Optimal number of Ports= " + num2str(app.n_ports_optimal);
                app.ValueObjectiveFunctionLabel.Text = "Maximum Revenue = " + num2str(app.revenue_optimal);

            elseif  Optimization_attribute == "Avg Waiting Time"

                for N_ports=1:20
                    station1 = ChargeStation(N_ports, P_ports);

                    % Start simulation
                    for minute=1:minutes_in_day
                        new_cars = arrive_cars(minute, AverageCarsPerMinute, C_bat);
                        station1 = station1.addCars(new_cars);
                        station1 = station1.update(minute);
                    end

                    revenue = app.revenue_function(station1, N_ports, VC_m_per_port)
                    waitingtime = station1.getWaitingTime()/station1.getCompletedCars();

                    n_ports_tested = [n_ports_tested, N_ports];
                    waiting_time_tested = [waiting_time_tested, waitingtime];
                    revenue_tested = [revenue_tested, revenue];

                    if waitingtime < app.waiting_time_optimal
                        app.n_ports_optimal = N_ports;
                        app.revenue_optimal = revenue;
                        app.waiting_time_optimal = waitingtime;
                    end
                end

                app.OptiAxes.YLabel.String = "Avg Waiting Time"
                sc_opti = scatter(app.OptiAxes, n_ports_tested, waiting_time_tested)
                app.OptimalNumberofportsLabel.Text = "Optimal number of Ports= " + num2str(app.n_ports_optimal);
                app.ValueObjectiveFunctionLabel.Text = "Min Avg Waiting Time = " + num2str(app.waiting_time_optimal);
            end
            app.OptimizationRunningLamp.Visible = 'off';
            app.OptimizationRunningLamp.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.Image3 = uiimage(app.StimulationTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'EV-charging-stations.jpg');
            app.EVchargingstationsLabel = uilabel(app.StimulationTab);
            app.EVchargingstationsLabel.Text = 'EV charging stations';
            app.NumberofchargingportsLabel = uilabel(app.StimulationTab);
            app.NumberofchargingportsLabel.Text = 'Number of charging ports';
            app.numberofports = uieditfield(app.StimulationTab, 'numeric');
            app.numberofports.ValueChangedFcn = createCallbackFcn(app, @numberofportsValueChanged, true);
            app.numberofports.Value = 10;
            app.ChargerpowerratingLabel = uilabel(app.StimulationTab);
            app.ChargerpowerratingLabel.Text = 'Charger power rating';
            app.powerrating = uieditfield(app.StimulationTab, 'numeric');
            app.powerrating.ValueChangedFcn = createCallbackFcn(app, @powerratingValueChanged, true);
            app.powerrating.Value = 50;
            app.EVbatterycapacityEditFieldLabel = uilabel(app.StimulationTab);
            app.EVbatterycapacityEditFieldLabel.Text = 'EV battery capacity';
            app.EVbatterycapacity = uieditfield(app.StimulationTab, 'numeric');
            app.EVbatterycapacity.ValueChangedFcn = createCallbackFcn(app, @EVbatterycapacityValueChanged, true);
            app.EVbatterycapacity.Value = 40;
            app.CararrivalrateLabel = uilabel(app.StimulationTab);
            app.CararrivalrateLabel.Text = 'Car arrival rate';
            app.arrivalrate = uieditfield(app.StimulationTab, 'numeric');
            app.arrivalrate.ValueChangedFcn = createCallbackFcn(app, @arrivalrateValueChanged, true);
            app.arrivalrate.Value = 0.3;
            app.SimulationspeedLabel = uilabel(app.StimulationTab);
            app.SimulationspeedLabel.Text = 'Simulation speed';
            app.SimSpeedInput = uieditfield(app.StimulationTab, 'numeric');
            app.SimSpeedInput.ValueChangedFcn = createCallbackFcn(app, @SimSpeedInputValueChanged, true);
            app.SimSpeedInput.Value = 10;
            app.TimeTextAreaLabel = uilabel(app.StimulationTab);
            app.TimeTextAreaLabel.Text = 'Time';
            app.TimeTextArea = uitextarea(app.StimulationTab);
            app.TimeTextArea.Value = {'20'};
            app.startstopsimulateButton = uibutton(app.StimulationTab, 'push');
            app.startstopsimulateButton.ButtonPushedFcn = createCallbackFcn(app, @startstopsimulateButtonPushed, true);
            app.startstopsimulateButton.Text = 'start/stop simulate';
            app.ResetButton = uibutton(app.StimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.viewreportexportplotsButton = uibutton(app.StimulationTab, 'push');
            app.viewreportexportplotsButton.ButtonPushedFcn = createCallbackFcn(app, @viewreportexportplotsButtonPushed, true);
            app.viewreportexportplotsButton.Text = {'view report'; '(export plots)'};
            app.RealTimeDataAnalysisPanel = uipanel(app.StimulationTab);
            app.RealTimeDataAnalysisPanel.Title = 'Real Time Data Analysis';
            app.UIAxes_3 = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes_3, 'Total Energy transfered')
            xlabel(app.UIAxes_3, 'Time of day')
            ylabel(app.UIAxes_3, 'Total energy transferred')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [0 240 480 720 960 1200 1440];
            app.UIAxes_3.XTickLabel = {'00:00'; '04:00'; '08:00'; '12:00'; '16:00'; '20:00'; '24:00'};
            app.UIAxes_2 = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes_2, 'EVs waiting')
            xlabel(app.UIAxes_2, 'Time of day')
            ylabel(app.UIAxes_2, 'Number of cars waiting')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [0 240 480 720 960 1200 1440];
            app.UIAxes_2.XTickLabel = {'00:00'; '04:00'; '08:00'; '12:00'; '16:00'; '20:00'; '24:00'};
            app.UIAxes = uiaxes(app.RealTimeDataAnalysisPanel);
            title(app.UIAxes, 'cost')
            xlabel(app.UIAxes, 'Time of day')
            ylabel(app.UIAxes, 'Cost in Euro')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 240 480 720 960 1200 1440];
            app.UIAxes.XTickLabel = {'00:00'; '04:00'; '08:00'; '12:00'; '16:00'; '20:00'; '24:00'};
            app.TotalEnergyLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.TotalEnergyLabel.Text = '0';
            app.TotalCostLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.TotalCostLabel.Text = '0';
            app.TotalWaitingLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.TotalWaitingLabel.Text = '0';
            app.CarqueueListBoxLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.CarqueueListBoxLabel.Text = 'Car queue';
            app.CarqueueListBox = uilistbox(app.RealTimeDataAnalysisPanel);
            app.CarqueueListBox.Items = {};
            app.CarqueueListBox.Value = {};
            app.Image2 = uiimage(app.RealTimeDataAnalysisPanel);
            app.ChargersListBoxLabel = uilabel(app.RealTimeDataAnalysisPanel);
            app.ChargersListBoxLabel.Text = 'Chargers';
            app.ChargersListBox = uilistbox(app.RealTimeDataAnalysisPanel);
            app.ChargersListBox.Items = {};
            app.ChargersListBox.Value = {};
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.Image4 = uiimage(app.OptimizationTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'EV-charging-stations_hole.png');
            app.OptimizeButton = uibutton(app.OptimizationTab, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonOushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.ObjectiveDropDownLabel = uilabel(app.OptimizationTab);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.ObjectiveDropDown = uidropdown(app.OptimizationTab);
            app.ObjectiveDropDown.Items = {'Revenue', 'Avg Waiting Time'};
            app.ObjectiveDropDown.Value = 'Revenue';
            app.OptimizationRunningLampLabel = uilabel(app.OptimizationTab);
            app.OptimizationRunningLampLabel.Enable = 'off';
            app.OptimizationRunningLampLabel.Visible = 'off';
            app.OptimizationRunningLampLabel.Text = 'Optimization Running';
            app.OptimizationRunningLamp = uilamp(app.OptimizationTab);
            app.OptimizationRunningLamp.Enable = 'off';
            app.OptimizationRunningLamp.Visible = 'off';
            app.ResultsPanel = uipanel(app.OptimizationTab);
            app.ResultsPanel.Title = 'Results';
            app.OptiAxes = uiaxes(app.ResultsPanel);
            title(app.OptiAxes, 'Optimization Progress')
            xlabel(app.OptiAxes, 'Number of Ports')
            ylabel(app.OptiAxes, 'Optimization Objective')
            zlabel(app.OptiAxes, 'Z')
            app.OptimalNumberofportsLabel = uilabel(app.ResultsPanel);
            app.OptimalNumberofportsLabel.Text = 'Optimal Number of ports';
            app.ValueObjectiveFunctionLabel = uilabel(app.ResultsPanel);
            app.ValueObjectiveFunctionLabel.Text = 'Value Objective Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end